classdef Player < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        Label       matlab.ui.control.Label
        LoadButton  matlab.ui.control.Button
        PlayButton  matlab.ui.control.Button
        StopButton  matlab.ui.control.StateButton
        Label_2     matlab.ui.control.Label
        Slider      matlab.ui.control.Slider
        UIAxes      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        filepath % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [file,path] = uigetfile('*.*');
            app.filepath = fullfile(path, file);
            % app.filepath = 'sample.wav'; % 디버깅용 코드
            
            app.PlayButton.Enable = true;
            app.StopButton.Enable = true;
            app.Slider.Enable = true;
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            % 설정
            fileReader = dsp.AudioFileReader(app.filepath, "SamplesPerFrame", 4000);
            deviceWriter = audioDeviceWriter('SampleRate',fileReader.SampleRate);
            
            % 저장공간
            store = zeros(fileReader.SampleRate*300, 1); % 사전 할당: 연산량 줄이기 위함
            seqnum = 0; % 프레임 순서를 나타냄
            
            % 실시간 처리
            while ~isDone(fileReader)
                drawnow % 중간에 다른 곳에서 값이 바뀌는 걸 업데이트
                if app.StopButton.Value == true % 정지버튼 기능
                    break;
                end
                process = @(x) x.*(app.Slider.Value/50);
                mySignal = fileReader(); % size : (app.SamplesPerFrame, 1)
                myProcessedSignal = process(mySignal);
                deviceWriter(myProcessedSignal);
                
                % 실시간 처리
                store(1 + seqnum*fileReader.SamplesPerFrame : (seqnum+1)*fileReader.SamplesPerFrame) = myProcessedSignal;
                seqnum = seqnum + 1;
            end
            
            % 파일 저장
            store = store(1:seqnum*fileReader.SamplesPerFrame); % 정보가 안 적힌 뒷부분 자르기
            audiowrite('r.wav', store, fileReader.SampleRate); % 오디오 저장하기
            
            plot(app.UIAxes, store);
            
            % 뒷정리
            release(fileReader)
            release(deviceWriter)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '볼륨 조절이 가능한 오디오 플레이어';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = '파일 불러오기';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Enable = 'off';
            app.PlayButton.Text = '재생';
            app.StopButton = uibutton(app.UIFigure, 'state');
            app.StopButton.Enable = 'off';
            app.StopButton.Text = '정지';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Enable = 'off';
            app.Label_2.Text = '볼륨';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Enable = 'off';
            app.Slider.Value = 50;
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '파형')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Player

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end