classdef demodulatorApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        ButtonGroup       matlab.ui.container.ButtonGroup
        Button_1          matlab.ui.control.RadioButton
        Button_4          matlab.ui.control.RadioButton
        Button_3          matlab.ui.control.RadioButton
        Button_2          matlab.ui.control.RadioButton
        Button_5          matlab.ui.control.RadioButton
        MATLABAMLabel     matlab.ui.control.Label
        SetupLabel        matlab.ui.control.Label
        SetupButton       matlab.ui.control.Button
        StartButton       matlab.ui.control.Button
        StopStateButton   matlab.ui.control.StateButton
        ChannelDiscLabel  matlab.ui.control.Label
        SaveButton        matlab.ui.control.Button
        Label             matlab.ui.control.Label
        EditField         matlab.ui.control.EditField
        Label_2           matlab.ui.control.Label
        EditField_2       matlab.ui.control.EditField
        wavLabel          matlab.ui.control.Label
    end

    
    properties (Access = private)
        LPF % Description
        HPF_array % Description
        fileReader % Description
        deviceWriter % Description
        process_Baseband % Description
        process_Modulated % Description
        carrier % Description
        ChannelNumber % Description
        SamplesPerFrame % Description
        fc % Description
        demodAudio % Description
    end
    
    methods (Access = private)
        
        function result = ChannelSelection(app, selectedButton)
            switch selectedButton
                case app.Button_1
                    result = 0;
                case app.Button_2
                    result = 1;
                case app.Button_3
                    result = 2;
                case app.Button_4
                    result = 3;
                case app.Button_5
                    result = 4;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SetupButton
        function SetupButtonPushed(app, event)
            app.fc = [0 4150 8250 12350 16450];
        
            %% Loader Initialization
            app.SamplesPerFrame = 2048;

            %% Filter Initialization
            % LPF
            h  = fdesign.lowpass(4050, 4150, 1, 60, 44100);
            app.LPF = design(h, 'cheby2', 'MatchExactly', 'stopband');
            
            % F1
            h  = fdesign.highpass(4100, 4150, 80, 1, 44100);
            HPF1 = design(h, 'cheby2', 'MatchExactly', 'stopband');
            % F2
            h  = fdesign.highpass(8200, 8250, 80, 1, 44100);
            HPF2 = design(h, 'cheby2', 'MatchExactly', 'stopband');
            % F3
            h  = fdesign.highpass(12300, 12350, 80, 1, 44100);
            HPF3= design(h, 'cheby2', 'MatchExactly', 'stopband');
            % F4
            h  = fdesign.highpass(16400, 16450, 80, 1, 44100);
            HPF4 = design(h, 'cheby2', 'MatchExactly', 'stopband');
                        
            app.HPF_array = [HPF1; HPF2; HPF3; HPF4];
            
            %% Carrier Initialization
            
            t = 0 : 1/44100 : (app.SamplesPerFrame + floor(app.SamplesPerFrame/2) - 1)/44100;
            app.carrier = cos(2*pi*app.fc(2:5)'*t)';
            
            %% Processing Functions
            app.process_Modulated = @(m, ch_num) 2 .* filter(app.LPF, ...
                filter(app.HPF_array(ch_num), m).*app.carrier(:, ch_num));
            app.process_Baseband = @(m) filter(app.LPF, m); 
            
            
            %% Unlock other components
            app.SetupButton.Enable = false;
            app.SetupLabel.Text = "시작을 눌러주세요.";
            app.ButtonGroup.Visible = true;
            app.ChannelDiscLabel.Visible = true;
            app.StartButton.Enable = true;
            app.StopStateButton.Enable = true;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % 사전 세팅
            app.fileReader = dsp.AudioFileReader('Filename', app.EditField.Value,'SamplesPerFrame', app.SamplesPerFrame);
            app.deviceWriter = audioDeviceWriter('SampleRate', 44100);
            app.SetupLabel.Text = "복조를 시작합니다.";
            store = zeros(44100 * 300, 1); % 저장 공간 Preallocation
            
            % padding을 고려한 Preallocation
            padsize= floor(app.SamplesPerFrame/4);
            bin = zeros(2 * padsize + app.SamplesPerFrame, 1);
            
            selectedButton = app.ButtonGroup.SelectedObject; % 채널 정보 받아오기
            tic; % 시간 계산용
            
            bin(2 * padsize + 1 : end) = app.fileReader(); % empty / audio
            app.ChannelNumber = ChannelSelection(app, selectedButton);
            app.ChannelDiscLabel.Text = "<현재 채널 정보> " + newline + "채널 번호: " + (app.ChannelNumber + 1) + newline...
                + "중심 주파수: " + app.fc(app.ChannelNumber + 1) + "Hz";
            
            if app.ChannelNumber == 0
                myProcessedSignal = app.process_Baseband(bin);
            else
                myProcessedSignal = app.process_Modulated(bin, app.ChannelNumber);
            end
            store(1:app.SamplesPerFrame, 1) = myProcessedSignal(padsize+1 :padsize+app.SamplesPerFrame); framenum = 1;
            
            while ~isDone(app.fileReader)
                app.deviceWriter(myProcessedSignal(padsize+1 : padsize+app.SamplesPerFrame)); % 재생을 먼저 시키고, 그동안 다음 프레임 작업
                
                % Inturrupt
                drawnow(); % loop inturrupt가 가능하게끔 해줌. delay 증가시킬 수 있으니 유의
                if app.StopStateButton.Value == 1
                    app.SetupLabel.Text = "복조를 중지합니다.";
                    release(app.deviceWriter)
                    break;
                end
                
                % 복조 파트
                bin(1:2*padsize) = bin(end+1-2*padsize:end);
                bin(2*padsize+1:end) = app.fileReader(); % 신호 받기 - bin [이전데이터 끝부분]
                drawnow(); % Loop Inturrupt를 위한 것
                if app.ChannelNumber == 0
                    myProcessedSignal = app.process_Baseband(bin);
                else
                    myProcessedSignal = app.process_Modulated(bin, app.ChannelNumber);
                end
                store(framenum*app.SamplesPerFrame + 1: (framenum + 1)*app.SamplesPerFrame, 1) = ... 
                    myProcessedSignal(padsize+1:padsize+app.SamplesPerFrame); framenum = framenum + 1;
            
            end
            app.demodAudio = store(1 : (framenum)*app.SamplesPerFrame, 1);
            app.SetupLabel.Text = "방송 끝! 총 " + floor(toc) + " 초 동안 재생했습니다.";
            app.SaveButton.Enable = true;
            
            release(app.deviceWriter)
            release(app.fileReader)
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            app.ChannelNumber = ChannelSelection(app, selectedButton);
            app.ChannelDiscLabel.Text = "<현재 채널 정보> " + newline + "채널 번호: " + (app.ChannelNumber + 1) + newline...
                + "중심 주파수: " + app.fc(app.ChannelNumber + 1) + "Hz";
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            audiowrite("demod_demo.wav", app.demodAudio, 44100);
            app.SetupLabel.Text = "수신 녹음 파일이 demod_demo.wav로 저장됐습니다";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = '채널 선택기';
            app.ButtonGroup.Visible = 'off';
            app.Button_1 = uiradiobutton(app.ButtonGroup);
            app.Button_1.Text = '채널1';
            app.Button_1.Value = true;
            app.Button_4 = uiradiobutton(app.ButtonGroup);
            app.Button_4.Text = '채널4';
            app.Button_3 = uiradiobutton(app.ButtonGroup);
            app.Button_3.Text = '채널3';
            app.Button_2 = uiradiobutton(app.ButtonGroup);
            app.Button_2.Text = '채널2';
            app.Button_5 = uiradiobutton(app.ButtonGroup);
            app.Button_5.Text = '채널5';
            app.MATLABAMLabel = uilabel(app.UIFigure);
            app.MATLABAMLabel.Text = {'2021 1학기 통신시스템설계'; 'MATLAB으로 만드는 AM 변조 방송 수신기'};
            app.SetupLabel = uilabel(app.UIFigure);
            app.SetupLabel.Text = '변조신호 파일명을 확인하고 Setup을 눌러 주세요.';
            app.SetupButton = uibutton(app.UIFigure, 'push');
            app.SetupButton.ButtonPushedFcn = createCallbackFcn(app, @SetupButtonPushed, true);
            app.SetupButton.Text = 'Setup';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Enable = 'off';
            app.StartButton.Text = '시작';
            app.StopStateButton = uibutton(app.UIFigure, 'state');
            app.StopStateButton.Enable = 'off';
            app.StopStateButton.Text = '중지';
            app.ChannelDiscLabel = uilabel(app.UIFigure);
            app.ChannelDiscLabel.Visible = 'off';
            app.ChannelDiscLabel.Text = {'<현재 채널 정보> '; '(없음)'};
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = '저장';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '불러올 변조 신호 파일명:';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Value = 'modulated_audio.wav';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '저장할 수신 신호 파일명: ';
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.EditField_2.Value = 'demod_audio';
            app.wavLabel = uilabel(app.UIFigure);
            app.wavLabel.Text = '.wav';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = demodulatorApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end