classdef modulator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        SetupLabel       matlab.ui.control.Label
        TimerLabel       matlab.ui.control.Label
        UIAxes           matlab.ui.control.UIAxes
        SaveButton       matlab.ui.control.Button
        SpectrumButton   matlab.ui.control.Button
        Label            matlab.ui.control.Label
        EditField        matlab.ui.control.EditField
        wavLabel         matlab.ui.control.Label
        Panel            matlab.ui.container.Panel
        SetupButton      matlab.ui.control.Button
        PlayButton       matlab.ui.control.Button
        StopStateButton  matlab.ui.control.StateButton
        MATLABAMLabel    matlab.ui.control.Label
    end

    
    properties (Access = private)
        BandPassFilter % BPF for Baseband
        HighPassFilter_array % HPF 1~4
        a0Reader % 방송국 1
        a1Reader % 방송국 2
        a2Reader % 방송국 3
        a3Reader % 방송국 4
        a4Reader % 방송국 5
        deviceWriter % 스피커 출력
        carrier % Description
        modulatedAudio % Description
        SamplesPerFrame % Description
    end
    
    methods (Access = private)
        function getFilters(app)
            % Baseband
            h  = fdesign.bandpass(5, 50, 4050, 4100, 60, 1, 80, 44100);
            app.BandPassFilter = design(h, 'cheby2', 'MatchExactly', 'stopband');
            
            % F1
            h  = fdesign.highpass(4100, 4150, 80, 1, 44100);
            HPF1 = design(h, 'cheby2', 'MatchExactly', 'stopband');
            
            % F2
            h  = fdesign.highpass(8200, 8250, 80, 1, 44100);
            HPF2 = design(h, 'cheby2', 'MatchExactly', 'stopband');
            
            % F3
            h  = fdesign.highpass(12300, 12350, 80, 1, 44100);
            HPF3= design(h, 'cheby2', 'MatchExactly', 'stopband');
            
            % F4
            h  = fdesign.highpass(16400, 16450, 80, 1, 44100);
            HPF4 = design(h, 'cheby2', 'MatchExactly', 'stopband');
            
            app.HighPassFilter_array = [HPF1; HPF2; HPF3; HPF4];
        end
        
        function carrierLine(app, fc, ch)
            xline(app.UIAxes, fc, 'Color', [1 0 0], 'LineWidth', 1.5, 'Label', "채널" + ch + ": "+ fc+"kHz");
            xline(app.UIAxes, -fc,'Color', [1 0 0], 'LineWidth', 1.5, 'Label', "-"+fc+"kHz");
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SetupButton
        function SetupButtonPushed(app, event)
            app.SamplesPerFrame = 10000;
            t = 0 : 1/44100 : (app.SamplesPerFrame - 1)/44100;
            app.carrier = cos(2*pi*[4150; 8250; 12350; 16450]*t)';
            
            getFilters(app);
            app.a0Reader = dsp.AudioFileReader('voice/news1.mp3', "SamplesPerFrame", app.SamplesPerFrame);
            app.a1Reader = dsp.AudioFileReader('voice/news2.mp3', "SamplesPerFrame", app.SamplesPerFrame);
            app.a2Reader = dsp.AudioFileReader('voice/news3.mp3', "SamplesPerFrame", app.SamplesPerFrame);
            app.a3Reader = dsp.AudioFileReader('voice/news4.mp3', "SamplesPerFrame", app.SamplesPerFrame);
            app.a4Reader = dsp.AudioFileReader('voice/news5.mp3', "SamplesPerFrame", app.SamplesPerFrame);
            app.deviceWriter = audioDeviceWriter('SampleRate',app.a0Reader.SampleRate);
            app.SetupLabel.Text = '방송을 시작할 준비를 마쳤습니다. [재생]을 눌러주세요.';
            app.SetupButton.Enable = 0;
            app.SetupLabel.FontColor = [0 0 1];
            app.PlayButton.Enable = 1; app.StopStateButton.Enable = 1;
            
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            tic;
            store = zeros(44100 * 300, 1); % Preallocation for 300 secs, (5min)
            
            % 변조 부분
            process_BaseBand = @(m) filter(app.BandPassFilter, m); % Baseband 변조
            process_Modulation = @(m) filter(app.BandPassFilter, m).*app.carrier; %
            process_Sum = @(b, m) filter(app.HighPassFilter_array(1), m(:, 1)) + filter(app.HighPassFilter_array(2), m(:, 2))...
                + filter(app.HighPassFilter_array(3), m(:, 3)) + filter(app.HighPassFilter_array(4), m(:, 4)) + b;
            
            % 첫 프레임 변조
            d0 = app.a0Reader(); d1 = app.a1Reader(); d2 = app.a2Reader(); d3 = app.a3Reader(); d4 = app.a4Reader();
            d0 = d0(:, 1); d = [d1(:, 1) d2(:, 1) d3(:, 1) d4(:, 1)];
            myProcessedSignal = process_Sum(process_BaseBand(d0), process_Modulation(d));
            
            
            store(1:app.SamplesPerFrame, 1) = myProcessedSignal;
            framenum = 1; % frame sequence number
            
            while ~(isDone(app.a0Reader) & isDone(app.a1Reader) & isDone(app.a2Reader) ...
                    & isDone(app.a3Reader) & isDone(app.a4Reader))
                app.deviceWriter(myProcessedSignal);
                drawnow(); % loop inturrupt가 가능하게끔 해줌. delay 증가시킬 수 있으니 유의
                
                if app.StopStateButton.Value == 1
                    app.SetupLabel.Text = "재생을 중지합니다.";
                    release(app.deviceWriter)
                    break;
                end
                
                % 받아오기 & 변조하기
                d0 = app.a0Reader(); d1 = app.a1Reader(); d2 = app.a2Reader(); d3 = app.a3Reader(); d4 = app.a4Reader();
                d0 = d0(:, 1); d = [d1(:, 1) d2(:, 1) d3(:, 1) d4(:, 1)];
                myProcessedSignal = process_Sum(process_BaseBand(d0), ...
                    process_Modulation(d));
                
                % 결과물 저장
                store(framenum*app.SamplesPerFrame + 1: (framenum + 1)*app.SamplesPerFrame, 1) = myProcessedSignal;
                framenum = framenum + 1;
            end
            % display
            app.modulatedAudio = store(1 : (framenum)*app.SamplesPerFrame, 1);
            app.TimerLabel.Text = "방송 끝! 총 " + floor(toc) + " 초 동안 재생했습니다.";
            app.SpectrumButton.Enable = 1;
            app.SaveButton.Enable = 1;
            
            % deallocation
            release(app.a0Reader);
            release(app.a1Reader);
            release(app.a2Reader);
            release(app.a3Reader);
            release(app.a4Reader);
            release(app.deviceWriter);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            filename = app.EditField.Value;
            audiowrite(filename + ".wav", app.modulatedAudio, 44100);
            app.SetupLabel.FontColor = [0 0 0];
            app.SetupLabel.Text = "방송 파일 ["+ filename +"]가 저장되었습니다.";
        end

        % Button pushed function: SpectrumButton
        function SpectrumButtonPushed(app, event)
            
            fny = 44100/2;
            fr = 44100/length(app.modulatedAudio);
            freq = -fny : fr : fny-fr;
            freq = freq./1000;
            plot(app.UIAxes, freq, 20*log10(abs(fftshift(fft(app.modulatedAudio)))));
            xtickformat(app.UIAxes, '%g kHz')
            ytickformat(app.UIAxes, '%g dB')
            title(app.UIAxes, "5개 방송국 주파수 분배 상황")
            xlabel(app.UIAxes, 'Freqency')
            
            xline(app.UIAxes, 0, 'Color', [1 0 0], 'LineWidth', 1.5, 'Label', "채널1: Baseband");
            carrierLine(app, 4.15, 2)
            carrierLine(app, 8.25, 3) 
            carrierLine(app, 12.35,4)
            carrierLine(app, 16.45,5)
            
            app.UIAxes.Visible = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.SetupLabel = uilabel(app.UIFigure);
            app.SetupLabel.Text = '[재생] 하기 전에 [Setup]버튼을 눌러주세요.';
            app.TimerLabel = uilabel(app.UIFigure);
            app.TimerLabel.Text = '';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.Visible = 'off';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = '파일 저장';
            app.SpectrumButton = uibutton(app.UIFigure, 'push');
            app.SpectrumButton.ButtonPushedFcn = createCallbackFcn(app, @SpectrumButtonPushed, true);
            app.SpectrumButton.Enable = 'off';
            app.SpectrumButton.Text = '주파수 스펙트럼 확인';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '저장할 파일명:';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Value = 'modulated_audio';
            app.wavLabel = uilabel(app.UIFigure);
            app.wavLabel.Text = '.wav';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '방송국';
            app.SetupButton = uibutton(app.Panel, 'push');
            app.SetupButton.ButtonPushedFcn = createCallbackFcn(app, @SetupButtonPushed, true);
            app.SetupButton.Text = 'Setup';
            app.PlayButton = uibutton(app.Panel, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Enable = 'off';
            app.PlayButton.Tooltip = {'5개 방송국의 신호를 변조한 음원을 재생합니다.'};
            app.PlayButton.Text = '재생';
            app.StopStateButton = uibutton(app.Panel, 'state');
            app.StopStateButton.Enable = 'off';
            app.StopStateButton.Text = '중지';
            app.MATLABAMLabel = uilabel(app.UIFigure);
            app.MATLABAMLabel.Text = {'2021 1학기 통신시스템설계'; 'MATLAB으로 만드는 AM 변조 방송 송신기'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = modulator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end