classdef image_RX < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        UIAxes       matlab.ui.control.UIAxes
        TitleLabel   matlab.ui.control.Label
        SaveButton   matlab.ui.control.Button
        InfoLabel    matlab.ui.control.Label
        SystemLabel  matlab.ui.control.Label
        DemodButton  matlab.ui.control.Button
        ExitButton   matlab.ui.control.Button
        Label        matlab.ui.control.Label
        Lamp         matlab.ui.control.Lamp
        StopButton   matlab.ui.control.Button
        wavButton    matlab.ui.control.Button
    end

    
    properties (Access = private)
        recorder
        preambleDetector
        received_data
        F0
        F1
        image_data % 복구한 이미지 데이터를 저장
        filename % 저장할 파일명
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.recorder = audiorecorder(48000, 16, 1);
            app.preambleDetector = comm.PreambleDetector([1 0 0 1 0 1]','Input','Bit','Detections',"First");
            Fs = 48000;  % Sampling Frequency

            % h  = fdesign.bandpass(Fstop1, Fpass1, Fpass2, Fstop2, Astop1, Apass, ...
                                  %Astop2, Fs); 
            h  = fdesign.bandpass(900, 1000, 3000, 3100, 60, 1, 80, Fs);
            app.F0 = design(h, 'cheby2', 'MatchExactly','stopband');
            h  = fdesign.bandpass(4900, 5000, 7000, 7100, 60, 1, 80, Fs);
            app.F1 = design(h, 'cheby2', 'MatchExactly', 'stopband');
        end

        % Button pushed function: DemodButton
        function DemodButtonPushed(app, event)
            app.SystemLabel.Text = "녹음을 시작합니다.";
            app.Lamp.Color = [0 1 0];
            drawnow;
            
            % 녹음 시작
            record(app.recorder);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            imwrite(app.image_data, app.filename);
            app.SystemLabel.Text = app.filename + "를 저장했습니다.";
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            close(app.UIFigure)
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            stop(app.recorder)
            app.Lamp.Color = [0 0 0];
            drawnow;
            
            data = getaudiodata(app.recorder);
            
            f = find(abs(data)>0.2);
            if numel(f)<100
                %% 잡힌 신호가 없다는 뜻
                app.SystemLabel.Text = '신호가 들어오지 않았습니다. 재녹음 해주세요.'; drawnpow;
            else
                %% 어느정도 신호가 잡힘
                % plot(app.UIAxes, data); drawnow; %신호 그리기
                
                % UI 안내문구
                app.SystemLabel.Text = "신호 복조를 시작합니다."; drawnow;
                
                % 안정적인 필터 적용을 위한 padding
                Ts = 48; % Sampling frequency / symbol rate
                data = [zeros(Ts*10, 1); data; zeros(Ts*10, 1)]; % 10bit 분량의 padding
                
                % Band Pass Filter -> 포락선 검파
  
                env1 = envelope(filter(app.F0, data));
                env2 = envelope(filter(app.F1, data));
                
                % ADC (analog -> binary)
                bin = uint8(env1(2:Ts:end) > env2(2:Ts:end) + 0.05);
                
                % preamble 찾아내기 (시작 지점 찾기)
                idx = app.preambleDetector(bin);
                rec_data = bin(idx+1:end); % pre 기준으로 슬라이싱
                % 오류정정코드
                rec_data = decode(rec_data, 7, 4, 'hamming/binary');
                
                % binary -> uint8
                rec = zeros(1, round(length(rec_data)/8));
                for i = 0:(floor(length(rec_data)/8)-1)
                    rec(i+1) = bi2de(rec_data(i*8 + 1 :(i+1)*8)');
                end
                
                % 데이터 분리
                app.filename = native2unicode(rec(4:rec(1)));
                imdata = rec(rec(1)+1 : rec(1)+rec(2)*rec(3));
                app.image_data = uint8(reshape(imdata, [rec(3), rec(2)]));
                
                % UI에 표시
                app.SystemLabel.Text = "이미지 데이터를 복조했습니다.";
                app.InfoLabel.Text = "파일명: " + app.filename + newline ...
                    + "이미지 높이 : " + rec(3) + newline ...
                    + "이미지 너비 : " + rec(2) + newline;
                imshow(app.image_data ,'Parent', app.UIAxes);
                drawnow;
                app.SaveButton.Enable = true;
            end
        end

        % Button pushed function: wavButton
        function wavButtonPushed(app, event)
            [file, path] = uigetfile('*.wav', '변조된 신호 선택');
            data = audioread(fullfile(path, file));
            
            app.SystemLabel.Text = "신호 복조를 시작합니다."; drawnow;
            
             % 안정적인 필터 적용을 위한 padding
            Ts = 48; % Sampling frequency / symbol rate
            data = [zeros(Ts*10, 1); data; zeros(Ts*10, 1)]; % 10bit 분량의 padding
            
            % Band Pass Filter -> 포락선 검파
    
            env1 = envelope(filter(app.F0, data));
            env2 = envelope(filter(app.F1, data));
            
            % ADC (analog -> binary)
            bin = uint8(env1(5:Ts:end) > env2(5:Ts:end) + 0.05);
            
            % preamble 찾아내기 (시작 지점 찾기)
            idx = app.preambleDetector(bin);
            rec_data = bin(idx+1:end); % pre 기준으로 슬라이싱
            % 오류정정코드
            rec_data = decode(rec_data, 7, 4, 'hamming/binary');
            
            % binary -> uint8
            rec = zeros(1, round(length(rec_data)/8));
            for i = 0:(floor(length(rec_data)/8)-1)
                rec(i+1) = bi2de(rec_data(i*8 + 1 :(i+1)*8)');
            end
            
            % 데이터 분리
            app.filename = native2unicode(rec(4:rec(1)));
            imdata = rec(rec(1)+1 : rec(1) + rec(2)*rec(3));
            app.image_data = uint8(reshape(imdata, [rec(3), rec(2)]));
            
            % UI에 표시
            app.SystemLabel.Text = "이미지 데이터를 복조했습니다.";
            app.InfoLabel.Text = "파일명: " + app.filename + newline ...
                + "이미지 높이 : " + rec(3) + newline ...
                + "이미지 너비 : " + rec(2) + newline;
            imshow(app.image_data ,'Parent', app.UIAxes);
            drawnow;
            app.SaveButton.Enable = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '수신 이미지')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.TitleLabel = uilabel(app.UIFigure);
            app.TitleLabel.Text = {'통신시스템설계'; '2차 설계 - 수신기'};
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = '파일 저장';
            app.InfoLabel = uilabel(app.UIFigure);
            app.InfoLabel.Text = '[정보]';
            app.SystemLabel = uilabel(app.UIFigure);
            app.SystemLabel.Text = '[녹음시작]을 눌러 음파를 수신합시다.';
            app.DemodButton = uibutton(app.UIFigure, 'push');
            app.DemodButton.ButtonPushedFcn = createCallbackFcn(app, @DemodButtonPushed, true);
            app.DemodButton.Text = '녹음시작';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = '종료';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '녹음';
            app.Lamp = uilamp(app.UIFigure);
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = '녹음종료';
            app.wavButton = uibutton(app.UIFigure, 'push');
            app.wavButton.ButtonPushedFcn = createCallbackFcn(app, @wavButtonPushed, true);
            app.wavButton.Text = 'wav 불러오기';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = image_RX

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end