classdef image_TX < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        ImageAxes            matlab.ui.control.UIAxes
        TitleLabel           matlab.ui.control.Label
        FileSelectionButton  matlab.ui.control.Button
        SendButton           matlab.ui.control.Button
        InfoLabel            matlab.ui.control.Label
        SystemLabel          matlab.ui.control.Label
        CloseButton          matlab.ui.control.Button
        wavButton            matlab.ui.control.Button
    end

    
    properties (Access = private)
        send_data % Description
        mod % Description
        Fs
    end
    
    methods (Access = private)    
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% FSK 변조 모듈 선언
            app.Fs = 48000;
            m = comm.FSKModulator;  
            m.ModulationOrder = 2;   % 사용되는 center frequency의 개수
            m.FrequencySeparation = 4e+3; % f0와 f1의 간격(Hz)
            m.SymbolRate = 1e+3;
            m.SamplesPerSymbol = app.Fs / m.SymbolRate;  % 한 비트 당 샘플 개수
            app.mod = m;
        end

        % Button pushed function: FileSelectionButton
        function FileSelectionButtonPushed(app, event)
            %% 파일 불러오기
            app.SystemLabel.Text = '전송하려는 파일을 선택해주세요.';
            
            imformat = {'*.*';'*.png'; '*.jpg'; '*.jpeg'; '*.tif'; '*.bmp'};
            [file, path] = uigetfile(imformat, '전송하려는 이미지 하나 선택');
            image_data = imread(fullfile(path, file));
            image_info = imfinfo(fullfile(path, file));
            
            %% UI에 파일정보 띄우기
            app.SystemLabel.Text = '파일을 읽는 중입니다... ';
            drawnow;
            
            imshow(image_data ,'Parent', app.ImageAxes);
            informations = "[이미지 정보]" + newline + ...
                "파일명: " + file + newline + ...
                "파일용량: " + image_info.FileSize + "Byte" + newline + ...
                "형식: " + image_info.Format + newline + ...
                "너비: " + image_info.Width + newline + ...
                "높이: " + image_info.Height + newline + ...
                "픽셀당 비트수: " + image_info.BitDepth;
            app.InfoLabel.Text = informations;
            
            %% 전송을 위한 전처리 section
            app.SystemLabel.Text = '전송을 준비하겠습니다... ';
            drawnow;
            
            % 전송할 데이터 구성 => data 변수에 담기
            image_metadata = [0 image_info.Width image_info.Height unicode2native(file)];
            image_metadata(1) = length(image_metadata);
            preamble = [1 0 0 1 0 1]';
            
            data = [image_metadata'; image_data(:)];
            
            % 데이터를 binary 열로 바꾸기 => send_data 변수에 담기
            s = de2bi(data, 8)';
            data = s(:); clear s;
            
            % Error Correction Ecoding (Hamming 7,4)
            data = encode(double(data), 7, 4, 'hamming/binary');
            data = [ones(48*10, 1);preamble; data; preamble;ones(48*10, 1)];
            
            %% 아날로그 신호 변환
            % 신호의 passband 변조
            data = app.mod(data); % data는 complex
            t = 0:1/app.Fs:(length(data)-1)/app.Fs;
            data = real(data).*cos(2*pi*4e+3.*t') + imag(data).*sin(2*pi*4e+3.*t'); % data는 real
            app.send_data = rescale(data, -1, 1);
            
            % UI에 안내문 표시 + 전송 버튼 Enable
            app.InfoLabel.Text = informations + newline + newline ...
                + "총 전송 데이터량은 " + numel(data)/app.mod.SamplesPerSymbol + "bits" + newline ...
                + "예상 전송 시간은" + numel(data)/app.Fs + "초";
            app.SystemLabel.Text = "전송할 준비를 마쳤습니다.";
            app.SystemLabel.FontColor = [0.98 0.73 0.25];
            app.SendButton.Enable = true;
        end

        % Button pushed function: SendButton
        function SendButtonPushed(app, event)
            soundsc(app.send_data, app.Fs);
            app.wavButton.Enable = true;
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            clc;
            close(app.UIFigure)
        end

        % Button pushed function: wavButton
        function wavButtonPushed(app, event)
            id_num = randi(10000, 1);
            filename = id_num + ".wav";
            audiowrite(filename, app.send_data, app.Fs);
            app.SystemLabel.Text = filename + " 을 저장했습니다.";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ImageAxes = uiaxes(app.UIFigure);
            title(app.ImageAxes, '전송할 이미지')
            xlabel(app.ImageAxes, 'X')
            ylabel(app.ImageAxes, 'Y')
            app.TitleLabel = uilabel(app.UIFigure);
            app.TitleLabel.Text = {'통신시스템설계'; '2차 설계 - 송신기'};
            app.FileSelectionButton = uibutton(app.UIFigure, 'push');
            app.FileSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @FileSelectionButtonPushed, true);
            app.FileSelectionButton.Text = '파일 선택';
            app.SendButton = uibutton(app.UIFigure, 'push');
            app.SendButton.ButtonPushedFcn = createCallbackFcn(app, @SendButtonPushed, true);
            app.SendButton.Enable = 'off';
            app.SendButton.Text = '전송';
            app.InfoLabel = uilabel(app.UIFigure);
            app.InfoLabel.Text = '[이미지가 없습니다]';
            app.SystemLabel = uilabel(app.UIFigure);
            app.SystemLabel.Text = '반갑습니다.';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = '종료';
            app.wavButton = uibutton(app.UIFigure, 'push');
            app.wavButton.ButtonPushedFcn = createCallbackFcn(app, @wavButtonPushed, true);
            app.wavButton.Enable = 'off';
            app.wavButton.Text = 'wav 저장';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = image_TX

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end